classdef musicapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LoggingMenu               matlab.ui.container.Menu
        LogginglevelMenu          matlab.ui.container.Menu
        DebugMenu                 matlab.ui.container.Menu
        InformationalMenu         matlab.ui.container.Menu
        WarningMenu               matlab.ui.container.Menu
        ErrorMenu                 matlab.ui.container.Menu
        FileLoggingToggleMenu     matlab.ui.container.Menu
        LogFilePathMenu           matlab.ui.container.Menu
        NormalizeMenu             matlab.ui.container.Menu
        NormalizeOnMenu           matlab.ui.container.Menu
        NormalizeOffMenu          matlab.ui.container.Menu
        GridLayout                matlab.ui.container.GridLayout
        Slider                    matlab.ui.control.Slider
        GridLayout2               matlab.ui.container.GridLayout
        OpenButton                matlab.ui.control.Button
        UIGetFileButton           matlab.ui.control.Button
        ChooseFileInput           matlab.ui.control.EditField
        ChooseFileEditFieldLabel  matlab.ui.control.Label
        LogLabel                  matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        PreviewTab                matlab.ui.container.Tab
        GridLayout6               matlab.ui.container.GridLayout
        ToLabel                   matlab.ui.control.Label
        AfterAxis                 matlab.ui.control.UIAxes
        BeforeAxis                matlab.ui.control.UIAxes
        EqualizerTab              matlab.ui.container.Tab
        GridLayout5               matlab.ui.container.GridLayout
        ApplyButton               matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        EqSlider10                matlab.ui.control.Slider
        kHzSlider_5Label          matlab.ui.control.Label
        EqSlider9                 matlab.ui.control.Slider
        kHzSlider_4Label          matlab.ui.control.Label
        EqSlider8                 matlab.ui.control.Slider
        kHzSlider_3Label          matlab.ui.control.Label
        EqSlider7                 matlab.ui.control.Slider
        kHzSlider_2Label          matlab.ui.control.Label
        EqSlider6                 matlab.ui.control.Slider
        kHzLabel                  matlab.ui.control.Label
        EqSlider5                 matlab.ui.control.Slider
        HzSlider_5Label           matlab.ui.control.Label
        EqSlider4                 matlab.ui.control.Slider
        HzSlider_4Label           matlab.ui.control.Label
        EqSlider3                 matlab.ui.control.Slider
        HzSlider_3Label           matlab.ui.control.Label
        EqSlider2                 matlab.ui.control.Slider
        HzSlider_2Label           matlab.ui.control.Label
        EqSlider1                 matlab.ui.control.Slider
        HzLabel                   matlab.ui.control.Label
        EqFigureTab               matlab.ui.container.Tab
        GridLayout7               matlab.ui.container.GridLayout
        ApplyButton_2             matlab.ui.control.Button
        ResetButton_2             matlab.ui.control.Button
        EqAxis                    matlab.ui.control.UIAxes
        GridLayout4               matlab.ui.container.GridLayout
        StopButton                matlab.ui.control.Button
        SaveAsButton              matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        PlayPauseButton           matlab.ui.control.Button
        GridLayout3               matlab.ui.container.GridLayout
        LengthLabel               matlab.ui.control.Label
        CurrLabel                 matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    properties(Constant)
        % constants
        LOGGINGLEVELSABBR (4,1) string = ["D";"I";"W";"E"];
        LOGGINGLEVELS (4,1) string = ["Debug: ";"Info: ";"Warning: ";"Error: "];
        LOGGINGCOLORS (4,3) double = [0, 0, 0; 0, 0, 0; 1, 0.65, 0; 1, 0, 0];
        SLIDERFREQUENCIES (1,10) double = [31.25, 62.5, 125, 250, 500, 1000, 2000, 4000, 8000, 16000];
    end

    properties (Access = private)
        % runtime generated constants
        INITDATETIME (1,1) datetime = datetime("now", "Format", "uuuuMMdd_HHmmss")
        
        % other properties
        LoggingLevel (1,1) uint8 = uint8(2); % 1-Debug, 2-Informational, 3-Warning, 4-Error
        LogFilePath = "";
        FileLogging = false;
        InputFilePath = fullfile(pwd, "music.mp3");
        OutputFilePath = "";
        BeforeMusic;
        AfterMusic;
        MusicSampleRate = 44100;
        MusicLength = uint64(0);
        TimePos; % cursor location
        MappingCurve;
        FFTX = [1];
        LGFFTX = [0];
        Step = 1;
        TimeX = [1];
        MusicLoaded = false;
        Converted = false;
        BeforeCursor;
        AfterCursor;
        PlayerCreated = false;
        Player;
        PlayerPlaying = false;
        StartPlayingTime = datetime;
        SliderValues = zeros(1,10);
        Normalize = false;
    end
    
    methods (Access = private)

        function resetLoggingMenuText(app)
            app.DebugMenu.Text = "Debug";
            app.InformationalMenu.Text = "Informational";
            app.WarningMenu.Text = "Warning";
            app.ErrorMenu.Text = "Error";
        end


        function resetSliders(app)
            logging(app, "Reset Sliders", 1);
            app.EqSlider1.Value = 0;
            app.EqSlider2.Value = 0;
            app.EqSlider3.Value = 0;
            app.EqSlider4.Value = 0;
            app.EqSlider5.Value = 0;
            app.EqSlider6.Value = 0;
            app.EqSlider7.Value = 0;
            app.EqSlider8.Value = 0;
            app.EqSlider9.Value = 0;
            app.EqSlider10.Value = 0;
        end

        function clearLogLabel(app)
            app.LogLabel.Text = "";
        end

        % logging with levels
        function logging(app, text, level)
            % any level of logging
            if level > 1
                clearLogLabel(app);
            end

            % logging level
            if level < app.LoggingLevel
                return
            end

            % loglabel
            app.LogLabel.FontColor = app.LOGGINGCOLORS(level, :);
            if level == 1
                app.LogLabel.FontWeight = 'normal';
            else
                app.LogLabel.FontWeight = 'bold';
            end
            app.LogLabel.Text = strcat(app.LOGGINGLEVELS(level),text);

            % file logging
            if app.FileLogging == false
                return
            end

            fid = fopen(app.LogFilePath, "a");

            % error opening the file
            if fid == -1
                app.LogLabel.FontColor = [1,0,0];
                app.LogLabel.Text = "Error: Log file cannot be created";
                app.LogLabel.FontWeight = 'bold';
                errordlg(strcat("Error: Log file cannot be created on ",app.LogFilePath), "Log File Creation Error", "replace")
                return
            end

            try
                % log with time
                timestamp = datetime("now", "Format", "yyyy-MM-dd HH:mm:ss.SS");
                fprintf(fid, "[%s] %s: %s\n", timestamp, app.LOGGINGLEVELSABBR(level), text);
            catch ME
                fclose(fid);
                app.LogLabel.FontColor = [1,0,0];
                app.LogLabel.FontWeight = 'bold';
                if strcmp(ME.identifier, 'MATLAB:FileIO:InvalidFid')
                    app.LogLabel.Text = "Error: File not found. Please check the filename.";
                    errordlg(strcat('File ', app.LogFilePath, ' not found. Please check the filename.'));
                else
                    app.LogLabel.Text = ME.message;
                    errordlg(ME.message); % Re-throw error if it’s unexpected
                    rethrow(ME);
                end
                return
            end
            fclose(fid);
        end

        % plot(app.BeforeAxis,X,app.AfterMusic(X,1),"Color",[0,0,1])

        function updateCurrLabel(app,value)
            logging(app, "Updating Curr Label" , 1);
            secs = value/app.MusicSampleRate;
            mins = floor(secs/60);
            secs = floor(mod(secs,60));
            app.CurrLabel.Text = strcat(num2str(mins, "%02d"), ":",num2str(secs, "%02d"));
        end

        function updateCursorLines(app, value)
            logging(app, "Updating cursors", 1);
            if (~isvalid(app.BeforeCursor)) || (~isvalid(app.AfterCursor))
                logging(app, "Cursors(animatedlines) Invalid, Recreating...", 3)
                app.BeforeCursor = animatedline(app.BeforeAxis, "Color", [0,0,0], "LineWidth", 2, "MaximumNumPoints", 2);
                app.AfterCursor = animatedline(app.AfterAxis, "Color", [0,0,0], "LineWidth", 2, "MaximumNumPoints", 2);
            end
            addpoints(app.BeforeCursor, value, -1.2);
            addpoints(app.BeforeCursor, value, 1.2);
            addpoints(app.AfterCursor, value, -1.2);
            addpoints(app.AfterCursor, value, 1.2);
            drawnow;
        end

        function fullStopPlayer(app)
            logging(app, "Full-Stopping Player", 1)
            if app.PlayerPlaying
                stop(app.Player)
            end
            app.PlayerPlaying = false;
            app.PlayerCreated = false;
            logging(app, "Player Stopped", 2)
        end

        function playerFinishes(app)
            % if the stopped location is more than 1 sec away from the end
            % the "stop" is actually a "pause"
            if app.Slider.Value + app.MusicSampleRate < app.MusicLength
                return
            end
            fullStopPlayer(app);
            app.Slider.Value = 0;
            updateCursorLines(app, 0)
            updateCurrLabel(app, 0)
            logging(app, "Finished Playing", 2)
        end

        function syncProgress(app)
            pause(1)
            while app.PlayerPlaying
                now = datetime;
                playtime = now - app.StartPlayingTime;
                app.StartPlayingTime = now;
                app.Slider.Value = min(app.Slider.Value + app.MusicSampleRate * seconds(playtime), app.MusicLength);
                updateCurrLabel(app,app.Slider.Value)
                updateCursorLines(app, app.Slider.Value)
                pause(0.5)
            end
        end

        function collectSliders(app)
            logging(app, "collecting slider values", 1)
            app.SliderValues(1) = app.EqSlider1.Value;
            app.SliderValues(2) = app.EqSlider2.Value;
            app.SliderValues(3) = app.EqSlider3.Value;
            app.SliderValues(4) = app.EqSlider4.Value;
            app.SliderValues(5) = app.EqSlider5.Value;
            app.SliderValues(6) = app.EqSlider6.Value;
            app.SliderValues(7) = app.EqSlider7.Value;
            app.SliderValues(8) = app.EqSlider8.Value;
            app.SliderValues(9) = app.EqSlider9.Value;
            app.SliderValues(10) = app.EqSlider10.Value;
        end

        function buildConversionMap(app)
            logging(app, "Conversion Map Calculating", 2)
            app.FFTX = linspace(0,app.MusicSampleRate-1,app.MusicLength);
            % app.LGFFTX = log(app.FFTX(2:app.MusicLength+1));
            collectSliders(app);
            logging(app, "PCHIP Calculating", 1)
            app.MappingCurve =  pchip( ...
                [0, app.SLIDERFREQUENCIES, app.MusicSampleRate ], ...
                [app.SliderValues(1), app.SliderValues, app.SliderValues(10)], ...
                app.FFTX);
            logging(app, "Conversion Map Calculated", 2)

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)

            logging(app,"App initializing",1)
            
            % init file logging = False
            app.LogFilePath = fullfile(pwd, strcat("logs/log_musicapp", string(app.INITDATETIME), ".txt"));
            app.FileLoggingToggleMenu.Text = "Turn On File Logging";
            app.FileLogging = false;

            % ChooseFileInput initialization
            app.ChooseFileInput.Value = app.InputFilePath;

            % ResetSliders
            resetSliders(app);

            % Initialize animatedlines
            app.BeforeCursor = animatedline(app.BeforeAxis, "Color", [0,0,0], "LineWidth", 2, "MaximumNumPoints", 2);
            app.AfterCursor = animatedline(app.AfterAxis, "Color", [0,0,0], "LineWidth", 2, "MaximumNumPoints", 2);


            % logging(LengthLabel,app.LogFilePath,4)    ;
            logging(app,"App initialized", 1);

        end

        % Button pushed function: UIGetFileButton
        function UIGetFile(app, event)
            logging(app, "uigetfile triggered", 1)
            [file, path] = uigetfile({'*.wav;*.mp3;*.flac;*.aiff;*.ogg', ...
                'Audio Files (*.wav;*.mp3;*.flac;*.aiff;*.ogg'}, ...
                'Select an Audio File');
            filename = fullfile(path, file);
            app.ChooseFileInput.Value = filename;
            app.InputFilePath = filename;
            logging(app, strcat("File selected :", filename), 2)
        end

        % Value changed function: ChooseFileInput
        function ChooseFileInputValueUpdated(app, event)
            app.InputFilePath = app.ChooseFileInput.Value;
            logging(app, "Input file path updated",1)
        end

        % Button pushed function: OpenButton
        function LoadMusicFile(app, event)
            logging(app, strcat("Loading ",app.InputFilePath), 2);
            try
                [app.BeforeMusic, app.MusicSampleRate] = audioread(app.InputFilePath);
            catch ME
                if strcmp(ME.identifier, "MATLAB:audiovideo:audioread:fileNotFound")
                    logging(app, strcat("File Not Found: ", app.InputFilePath),4)
                else
                    logging(app,ME.message,4);
                end
                return
            end
            
            logging(app, "Resetting player labels", 1);
            sz = size(app.BeforeMusic,1);
            app.Step = max(1,floor(sz/100000));
            app.TimeX = 1:app.Step:sz;
            app.MusicLength = size(app.BeforeMusic,1);
            app.LengthLabel.Text = strcat( ...
                num2str(floor(app.MusicLength/60/app.MusicSampleRate),"%02d"),':', ...
                num2str(floor(mod(app.MusicLength/app.MusicSampleRate,60)),"%02d"));
            updateCurrLabel(app, 0)
            
            logging(app, "Resetting player slider", 1);
            app.Slider.Value = 0;
            app.Slider.Limits = [0, app.MusicLength];
            app.Slider.Value = 0;
            updateCurrLabel(app, app.Slider.Value)

            logging(app, "Resetting Player", 1);
            fullStopPlayer(app);
            

            logging(app, "Resetting axes", 1);
            cla(app.BeforeAxis);
            cla(app.AfterAxis);
            app.BeforeAxis.XLim = [0, sz];
            app.AfterAxis.XLim = [0, sz];

            logging(app, "Plotting Before Music", 1);
            plot(app.BeforeAxis,app.TimeX,app.BeforeMusic(app.TimeX,1),"Color",[0,0,1])

            logging(app, "Resetting conversion status", 1);
            app.AfterMusic = app.BeforeMusic;
            app.Converted = false;
            
            logging(app, "Plotting Cursor line", 1);
            app.BeforeCursor = animatedline(app.BeforeAxis, "Color", [0,0,0], "LineWidth", 2, "MaximumNumPoints", 2);
            app.AfterCursor = animatedline(app.AfterAxis, "Color", [0,0,0], "LineWidth", 2, "MaximumNumPoints", 2);
            updateCursorLines(app, app.Slider.Value);
               
            logging(app, "Resetting tab", 1)
            app.TabGroup.SelectedTab = app.PreviewTab;

            logging(app, strcat("File """, app.InputFilePath, """ successfully loaded"), 2);
            app.MusicLoaded = true;
        end

        % Menu selected function: DebugMenu
        function DebugMenuSelected(app, event)
            app.LoggingLevel = uint8(1);
            resetLoggingMenuText(app)
            app.DebugMenu.Text = "Debug (v)";
            logging(app, "Logging Level: Debug", 1)
        end

        % Menu selected function: InformationalMenu
        function InformationalMenuSelected(app, event)
            app.LoggingLevel = uint8(2);
            resetLoggingMenuText(app)
            app.InformationalMenu.Text = "Informational (v)";
            logging(app, "Logging Level: Informational", 2)
        end

        % Menu selected function: WarningMenu
        function WarningMenuSelected(app, event)
            app.LoggingLevel = uint8(3);
            resetLoggingMenuText(app)
            app.WarningMenu.Text = "Warning (v)";
            logging(app, "Logging Level: Warning", 2)
        end

        % Menu selected function: ErrorMenu
        function ErrorMenuSelected(app, event)
            app.LoggingLevel = uint8(4);
            resetLoggingMenuText(app)
            app.ErrorMenu.Text = "Error (v)";
            logging(app, "Logging Level: Error", 2)
        end

        % Menu selected function: FileLoggingToggleMenu
        function FileLoggingToggleMenuSelected(app, event)
            if app.FileLogging
                app.FileLoggingToggleMenu.Text = "Turn On File Logging";
                app.FileLogging = false;
                logging(app,"File Logging Turned Off", 1)
            else
                app.FileLoggingToggleMenu.Text = "Turn Off File Logging";
                app.FileLogging = true;
                fid = fopen(app.LogFilePath, "a");
                if fid == -1
                    app.FileLoggingToggleMenu.Text = "Turn On File Logging";
                    app.FileLogging = false;
                    logging(app, "Log File Creation Error, File-logging Not Turned On",3);
                    return
                else
                    fclose(fid);
                end
                logging(app,"File Logging Turned On", 1)
            end
        end

        % Menu selected function: LogFilePathMenu
        function LogFilePathMenuSelected(app, event)
            [file,location] = uiputfile(app.LogFilePath);
            app.LogFilePath = fullfile(location, file);
            logging(app, strcat("Log file location set: ", app.LogFilePath),2)
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            if app.PlayerPlaying|| app.PlayerCreated
                fullStopPlayer(app)
            end
            logging(app, "Slider Value Changing" , 1);
            changingValue = event.Value;
            updateCursorLines(app, changingValue);
            updateCurrLabel(app, changingValue);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            updateCursorLines(app, value);
            updateCurrLabel(app, value);
            app.PlayerCreated = false;
            logging(app, "Slider Value Changed" , 1);
        end

        % Button pushed function: PlayPauseButton
        function PlayPausePushed(app, event)
            % no music loaded: error
            if ~ app.MusicLoaded
                logging(app, "No Music Loaded", 4)
                return
            end
            
            % Playing: Pause
            if app.PlayerCreated && app.PlayerPlaying 
                pause(app.Player);
                app.PlayerPlaying = false;
                logging(app, "Player Paused", 2);
                return
            end
            
            % Paused: Resume
            if app.PlayerCreated && (~ app.PlayerPlaying )
                resume(app.Player);
                app.StartPlayingTime = datetime;
                app.PlayerPlaying = true;
                logging(app, "Player Resumed", 2);
                syncProgress(app)
                return
            end
            
            % Unexpected status
            if app.PlayerPlaying
                logging(app, "Player Not Created But Playing", 3)
            end
            
            % Player not existing: Create new Player + Play
            logging(app, "Player Creating", 1);

            sliced = app.AfterMusic(floor(max(app.Slider.Value,1)):app.MusicLength, :);
            app.Player = audioplayer(sliced, app.MusicSampleRate);
            app.Player.StopFcn = @(src, event) playerFinishes(app);
            % createCallbackFcn(app, @fullStopPlayer, true);
            app.PlayerCreated = true;
            play(app.Player);
            app.StartPlayingTime = datetime;
            app.PlayerPlaying = true;
            logging(app, "Playing Started", 2)
            
            % Play function that miraculously works on parallel threads
            syncProgress(app)

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            logging(app, "Player Stopping", 1)
            if app.PlayerCreated
                fullStopPlayer(app);
                app.Slider.Value = 0;
                updateCursorLines(app, 0)
                updateCurrLabel(app, 0)
                logging(app, "Player Stopped", 2);
            end
        end

        % Button pushed function: ResetButton, ResetButton_2
        function ResetButtonPushed(app, event)
            resetSliders(app)
            app.TabGroup.SelectedTab = app.EqualizerTab;
            logging(app, "Sliders Resetted", 2)
        end

        % Button down function: EqFigureTab
        function EqFigureTabButtonDown(app, event)
            if ~ app.MusicLoaded
                logging(app, "Load Music To apply equalizer", 3)
                return
            end
            buildConversionMap(app);
            cla(app.EqAxis);
            X = app.FFTX(1:app.Step:app.MusicLength);
            Y = app.MappingCurve(1:app.Step:app.MusicLength);
            plot(app.EqAxis, X, Y, "Color", [0,0,1])
            hold(app.EqAxis, "on")
            scatter(app.EqAxis, app.SLIDERFREQUENCIES, app.SliderValues, 'filled','o','MarkerFaceColor',[0,0,0])
            hold(app.EqAxis, "off")
            logging(app, "Equalizer Plot Updated", 2)
        end

        % Button pushed function: ApplyButton, ApplyButton_2
        function ApplyButtonPushed(app, event)
            if ~ app.MusicLoaded
                logging(app, "Load Music To apply equalizer", 4)
                return
            end
            logging (app, "Equalizer Applying", 2)
            buildConversionMap(app)
            app.fullStopPlayer;
            logging(app, "Transforming", 1)
            if app.Normalize
                app.AfterMusic = applyTransformNormalize(app.BeforeMusic, app.MappingCurve(1:2:app.MusicLength-1));
            else
                app.AfterMusic = applyTransformMax(app.BeforeMusic, app.MappingCurve(1:2:app.MusicLength-1));
            end
            logging(app, "Plotting After Music", 1);
            plot(app.AfterAxis,app.TimeX,app.AfterMusic(app.TimeX,1),"Color",[0,0,1])
            app.AfterCursor = animatedline(app.AfterAxis, "Color", [0,0,0], "LineWidth", 2, "MaximumNumPoints", 2);
            logging(app, "Equalizer Applied",2)
            app.Converted = true;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if ~app.Converted
                logging(app, "Save button pressed while not converted", 1)
                return
            end
            audiowrite(app.InputFilePath, app.AfterMusic, app.MusicSampleRate);
            logging(app, strcat("Music Saved to ", app.InputFilePath),2)
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            if ~app.Converted
                logging(app, "SaveAs button pressed while not converted", 1)
                return
            end
            [file, location] = uiputfile({'*.wav;*.mp3;*.flac;*.aiff;*.ogg',...
                'Audio Files (*.wav;*.mp3;*.flac;*.aiff;*.ogg'},...
                "Save Transformed Audio File");
            if ischar(file) && ischar(location) && ~isequal(file, 0) && ~isequal(location, 0)
                outputFilePath = fullfile(location, file);
                audiowrite(outputFilePath, app.AfterMusic, app.MusicSampleRate);
                logging(app, strcat("Transformed music file saved in ",outputFilePath), 2)
            else
                logging(app, "File location not chosen", 4)
            end
        end

        % Menu selected function: NormalizeOnMenu
        function NormalizeOn(app, event)
            app.NormalizeOnMenu.Text = "On (v)";
            app.NormalizeOffMenu.Text = "Off";
            
            if app.Normalize
                logging(app, "Normalize On -> On", 1);
            else
                app.Normalize = true;
                logging(app, "Normalize Turned On", 2);
            end
        end

        % Menu selected function: NormalizeOffMenu
        function NormalizeOff(app, event)
            app.NormalizeOnMenu.Text = "On";
            app.NormalizeOffMenu.Text = "Off (v)";
            
            if ~ app.Normalize
                logging(app, "Normalize Off -> Off", 1);
            else
                app.Normalize = false;
                logging(app, "Normalize Turned Off", 2);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoggingMenu = uimenu(app.UIFigure);
            app.LoggingMenu.Text = 'Logging';
            app.LogginglevelMenu = uimenu(app.LoggingMenu);
            app.LogginglevelMenu.Text = 'Logging level';
            app.DebugMenu = uimenu(app.LogginglevelMenu);
            app.DebugMenu.MenuSelectedFcn = createCallbackFcn(app, @DebugMenuSelected, true);
            app.DebugMenu.Text = 'Debug';
            app.InformationalMenu = uimenu(app.LogginglevelMenu);
            app.InformationalMenu.MenuSelectedFcn = createCallbackFcn(app, @InformationalMenuSelected, true);
            app.InformationalMenu.Text = 'Informational (v)';
            app.WarningMenu = uimenu(app.LogginglevelMenu);
            app.WarningMenu.MenuSelectedFcn = createCallbackFcn(app, @WarningMenuSelected, true);
            app.WarningMenu.Text = 'Warning';
            app.ErrorMenu = uimenu(app.LogginglevelMenu);
            app.ErrorMenu.MenuSelectedFcn = createCallbackFcn(app, @ErrorMenuSelected, true);
            app.ErrorMenu.Text = 'Error';
            app.FileLoggingToggleMenu = uimenu(app.LoggingMenu);
            app.FileLoggingToggleMenu.MenuSelectedFcn = createCallbackFcn(app, @FileLoggingToggleMenuSelected, true);
            app.FileLoggingToggleMenu.Text = 'Turn On File Logging';
            app.LogFilePathMenu = uimenu(app.LoggingMenu);
            app.LogFilePathMenu.MenuSelectedFcn = createCallbackFcn(app, @LogFilePathMenuSelected, true);
            app.LogFilePathMenu.Text = 'Log File Path';
            app.NormalizeMenu = uimenu(app.UIFigure);
            app.NormalizeMenu.Text = 'Normalize';
            app.NormalizeOnMenu = uimenu(app.NormalizeMenu);
            app.NormalizeOnMenu.MenuSelectedFcn = createCallbackFcn(app, @NormalizeOn, true);
            app.NormalizeOnMenu.Text = 'On';
            app.NormalizeOffMenu = uimenu(app.NormalizeMenu);
            app.NormalizeOffMenu.MenuSelectedFcn = createCallbackFcn(app, @NormalizeOff, true);
            app.NormalizeOffMenu.Text = 'Off (v)';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.CurrLabel = uilabel(app.GridLayout3);
            app.CurrLabel.Text = '00:00';
            app.LengthLabel = uilabel(app.GridLayout3);
            app.LengthLabel.Text = '00:01';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.PlayPauseButton = uibutton(app.GridLayout4, 'push');
            app.PlayPauseButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPausePushed, true);
            app.PlayPauseButton.Icon = fullfile(pathToMLAPP, 'icons', 'PlayPauseIcon.png');
            app.PlayPauseButton.Text = '';
            app.SaveButton = uibutton(app.GridLayout4, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SaveAsButton = uibutton(app.GridLayout4, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Text = 'Save As';
            app.StopButton = uibutton(app.GridLayout4, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, 'icons', 'StopButtonIcon.png');
            app.StopButton.Text = '';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PreviewTab = uitab(app.TabGroup);
            app.PreviewTab.Title = 'Preview';
            app.GridLayout6 = uigridlayout(app.PreviewTab);
            app.BeforeAxis = uiaxes(app.GridLayout6);
            app.BeforeAxis.YLim = [-1.1 1.1];
            app.BeforeAxis.XTick = [];
            app.BeforeAxis.YTick = [];
            app.AfterAxis = uiaxes(app.GridLayout6);
            app.AfterAxis.XLim = [0 1];
            app.AfterAxis.YLim = [-1.1 1.1];
            app.AfterAxis.XTick = [];
            app.AfterAxis.YTick = [];
            app.ToLabel = uilabel(app.GridLayout6);
            app.ToLabel.Text = '⬇';
            app.EqualizerTab = uitab(app.TabGroup);
            app.EqualizerTab.Title = 'Equalizer';
            app.GridLayout5 = uigridlayout(app.EqualizerTab);
            app.HzLabel = uilabel(app.GridLayout5);
            app.HzLabel.Text = '31.3 Hz';
            app.EqSlider1 = uislider(app.GridLayout5);
            app.EqSlider1.Limits = [-15 15];
            app.EqSlider1.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.HzSlider_2Label = uilabel(app.GridLayout5);
            app.HzSlider_2Label.Text = '62.5 Hz';
            app.EqSlider2 = uislider(app.GridLayout5);
            app.EqSlider2.Limits = [-15 15];
            app.EqSlider2.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.HzSlider_3Label = uilabel(app.GridLayout5);
            app.HzSlider_3Label.Text = '125 Hz';
            app.EqSlider3 = uislider(app.GridLayout5);
            app.EqSlider3.Limits = [-15 15];
            app.EqSlider3.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.HzSlider_4Label = uilabel(app.GridLayout5);
            app.HzSlider_4Label.Text = '250 Hz';
            app.EqSlider4 = uislider(app.GridLayout5);
            app.EqSlider4.Limits = [-15 15];
            app.EqSlider4.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.HzSlider_5Label = uilabel(app.GridLayout5);
            app.HzSlider_5Label.Text = '500 Hz';
            app.EqSlider5 = uislider(app.GridLayout5);
            app.EqSlider5.Limits = [-15 15];
            app.EqSlider5.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.kHzLabel = uilabel(app.GridLayout5);
            app.kHzLabel.Text = '1 kHz';
            app.EqSlider6 = uislider(app.GridLayout5);
            app.EqSlider6.Limits = [-15 15];
            app.EqSlider6.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.kHzSlider_2Label = uilabel(app.GridLayout5);
            app.kHzSlider_2Label.Text = '2 kHz';
            app.EqSlider7 = uislider(app.GridLayout5);
            app.EqSlider7.Limits = [-15 15];
            app.EqSlider7.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.kHzSlider_3Label = uilabel(app.GridLayout5);
            app.kHzSlider_3Label.Text = '4 kHz';
            app.EqSlider8 = uislider(app.GridLayout5);
            app.EqSlider8.Limits = [-15 15];
            app.EqSlider8.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.kHzSlider_4Label = uilabel(app.GridLayout5);
            app.kHzSlider_4Label.Text = '8 kHz';
            app.EqSlider9 = uislider(app.GridLayout5);
            app.EqSlider9.Limits = [-15 15];
            app.EqSlider9.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.kHzSlider_5Label = uilabel(app.GridLayout5);
            app.kHzSlider_5Label.Text = '16 kHz';
            app.EqSlider10 = uislider(app.GridLayout5);
            app.EqSlider10.Limits = [-15 15];
            app.EqSlider10.MajorTicks = [-15 -10 -5 0 5 10 15];
            app.ResetButton = uibutton(app.GridLayout5, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ApplyButton = uibutton(app.GridLayout5, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.EqFigureTab = uitab(app.TabGroup);
            app.EqFigureTab.Title = 'EqFigure';
            app.EqFigureTab.ButtonDownFcn = createCallbackFcn(app, @EqFigureTabButtonDown, true);
            app.GridLayout7 = uigridlayout(app.EqFigureTab);
            app.EqAxis = uiaxes(app.GridLayout7);
            title(app.EqAxis, 'Equalizer Plot')
            xlabel(app.EqAxis, 'frequency (Hz)')
            ylabel(app.EqAxis, 'Gain(dB)')
            zlabel(app.EqAxis, 'Z')
            app.EqAxis.XLim = [20 20000];
            app.EqAxis.YLim = [-15 15];
            app.EqAxis.XTick = [31.25 62.5 125 250 500 1000 2000 4000 8000 16000];
            app.EqAxis.XScale = 'log';
            app.EqAxis.XTickLabel = {'31.25'; '62.5'; '125'; '250'; '500'; '1000'; '2000'; '4000'; '8000'; '16000'};
            app.EqAxis.YTick = [-15 -10 -5 0 5 10 15];
            app.ResetButton_2 = uibutton(app.GridLayout7, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.ApplyButton_2 = uibutton(app.GridLayout7, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.LogLabel = uilabel(app.GridLayout);
            app.LogLabel.Text = '';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ChooseFileEditFieldLabel = uilabel(app.GridLayout2);
            app.ChooseFileEditFieldLabel.Text = 'Choose File';
            app.ChooseFileInput = uieditfield(app.GridLayout2, 'text');
            app.ChooseFileInput.ValueChangedFcn = createCallbackFcn(app, @ChooseFileInputValueUpdated, true);
            app.UIGetFileButton = uibutton(app.GridLayout2, 'push');
            app.UIGetFileButton.ButtonPushedFcn = createCallbackFcn(app, @UIGetFile, true);
            app.UIGetFileButton.Icon = fullfile(pathToMLAPP, 'icons', 'UIGetFileButtonIcon.png');
            app.UIGetFileButton.Text = '';
            app.OpenButton = uibutton(app.GridLayout2, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMusicFile, true);
            app.OpenButton.Text = 'Open';
            app.Slider = uislider(app.GridLayout);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = musicapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end